classdef app_03_submit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UserInformationListBox       matlab.ui.control.ListBox
        UserInformationListBoxLabel  matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        LevelDropDown                matlab.ui.control.DropDown
        LevelDropDownLabel           matlab.ui.control.Label
        GenderDropDown               matlab.ui.control.DropDown
        GenderDropDownLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.UserInformationListBox.Items = {
                app.NameEditField.Value;
                app.GenderDropDown.Value;
                app.LevelDropDown.Value;
            };
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.NameEditField.Value = "";
            app.GenderDropDown.Value = app.GenderDropDown.Items{1};
            app.LevelDropDown.Value = app.LevelDropDown.Items{1};
            app.UserInformationListBox.Items = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GenderDropDownLabel = uilabel(app.GridLayout);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.GridLayout);
            app.GenderDropDown.Items = {'Female', 'Male'};
            app.GenderDropDown.Placeholder = 'You Gender';
            app.GenderDropDown.Value = 'Female';
            app.LevelDropDownLabel = uilabel(app.GridLayout);
            app.LevelDropDownLabel.Text = 'Level';
            app.LevelDropDown = uidropdown(app.GridLayout);
            app.LevelDropDown.Items = {'Class I', 'Class II', 'Class III'};
            app.LevelDropDown.Placeholder = 'Your Level';
            app.LevelDropDown.Value = 'Class I';
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.UserInformationListBoxLabel = uilabel(app.GridLayout);
            app.UserInformationListBoxLabel.Text = 'User Information';
            app.UserInformationListBox = uilistbox(app.GridLayout);
            app.UserInformationListBox.Items = {};
            app.UserInformationListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_03_submit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end