classdef app_05_lamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ClearButton           matlab.ui.control.Button
        CloseButton           matlab.ui.control.Button
        TestButton            matlab.ui.control.Button
        TemperatureLamp       matlab.ui.control.Lamp
        TemperatureLampLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TemperatureLampLabel.Text = "Temperature";
            app.TemperatureLamp.Color = "green";
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            r = double(py.utils.temperature_getter());
            if r < 36.5
                app.TemperatureLamp.Color = "0.00,1.00,0.00";
                app.TemperatureLampLabel.Text = string(r) + "℃";
            elseif (36.5 <= r) && (r <= 38)
                app.TemperatureLamp.Color = "0.93,0.69,0.13";
                app.TemperatureLampLabel.Text = string(r) + "℃";
            else
                app.TemperatureLamp.Color = "0.87,0.33,0.00";
                app.TemperatureLampLabel.Text = string(r) + "℃";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TemperatureLampLabel = uilabel(app.GridLayout);
            app.TemperatureLampLabel.Text = 'Temperature';
            app.TemperatureLamp = uilamp(app.GridLayout);
            app.TemperatureLamp.Tooltip = {'Green: Under 36.5 ℃'; 'Yellow: Between 36.5 ℃ and 38 ℃'; 'Red: Over 38 ℃'};
            app.TestButton = uibutton(app.GridLayout, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_05_lamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end