classdef app_06_switch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        CloseButton        matlab.ui.control.Button
        ClickButton        matlab.ui.control.Button
        LeftSwitch         matlab.ui.control.Switch
        LeftSwitchLabel    matlab.ui.control.Label
        RLamp              matlab.ui.control.Lamp
        RLampLabel         matlab.ui.control.Label
        MLamp              matlab.ui.control.Lamp
        MLampLabel         matlab.ui.control.Label
        LLamp              matlab.ui.control.Lamp
        LLampLabel         matlab.ui.control.Label
        RightSwitch        matlab.ui.control.RockerSwitch
        RightSwitchLabel   matlab.ui.control.Label
        MiddleSwitch       matlab.ui.control.ToggleSwitch
        MiddleSwitchLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ClickButton
        function ClickButtonPushed(app, event)
            rL = string(py.utils.switch_getter());
            rM = string(py.utils.switch_getter());
            rR = string(py.utils.switch_getter());

            if strcmp(rL, "On")
                app.LLamp.Color = "green";
            else
                app.LLamp.Color = "red";
            end
            
            if strcmp(rM, "On")
                app.MLamp.Color = "green";
            else
                app.MLamp.Color = "red";
            end

            if strcmp(rR, "On")
                app.RLamp.Color = "green";
            else
                app.RLamp.Color = "red";
            end
        end

        % Value changed function: LeftSwitch
        function LeftSwitchValueChanged(app, event)
            value = app.LeftSwitch.Value;
            if strcmp(value, "On")
                app.LLamp.Color = "green";
            else
                app.LLamp.Color = "red";
            end
        end

        % Value changed function: MiddleSwitch
        function MiddleSwitchValueChanged(app, event)
            value = app.MiddleSwitch.Value;
            if strcmp(value, "On")
                app.MLamp.Color = "green";
            else
                app.MLamp.Color = "red";
            end
        end

        % Value changed function: RightSwitch
        function RightSwitchValueChanged(app, event)
            value = app.RightSwitch.Value;
            if strcmp(value, "On")
                app.RLamp.Color = "green";
            else
                app.RLamp.Color = "red";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MiddleSwitchLabel = uilabel(app.GridLayout);
            app.MiddleSwitchLabel.Text = 'Middle';
            app.MiddleSwitch = uiswitch(app.GridLayout, 'toggle');
            app.MiddleSwitch.ValueChangedFcn = createCallbackFcn(app, @MiddleSwitchValueChanged, true);
            app.RightSwitchLabel = uilabel(app.GridLayout);
            app.RightSwitchLabel.Text = 'Right';
            app.RightSwitch = uiswitch(app.GridLayout, 'rocker');
            app.RightSwitch.ValueChangedFcn = createCallbackFcn(app, @RightSwitchValueChanged, true);
            app.LLampLabel = uilabel(app.GridLayout);
            app.LLampLabel.Text = 'L';
            app.LLamp = uilamp(app.GridLayout);
            app.MLampLabel = uilabel(app.GridLayout);
            app.MLampLabel.Text = 'M';
            app.MLamp = uilamp(app.GridLayout);
            app.RLampLabel = uilabel(app.GridLayout);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.GridLayout);
            app.LeftSwitchLabel = uilabel(app.GridLayout);
            app.LeftSwitchLabel.Text = 'Left';
            app.LeftSwitch = uiswitch(app.GridLayout, 'slider');
            app.LeftSwitch.ValueChangedFcn = createCallbackFcn(app, @LeftSwitchValueChanged, true);
            app.ClickButton = uibutton(app.GridLayout, 'push');
            app.ClickButton.ButtonPushedFcn = createCallbackFcn(app, @ClickButtonPushed, true);
            app.ClickButton.Text = 'Click';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_06_switch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end