classdef app_07_chart_controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        QuitButton   matlab.ui.control.Button
        ResetButton  matlab.ui.control.Button
        ChartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: ChartButton
        function ChartButtonPushed(app, event)
            r = randi(5);
            metric = readmatrix("data/train.csv");
            X = metric(:, 1);
            Y = metric(:, 2);
            plot(app.UIAxes, X, Y, "red");
            hold(app.UIAxes, "on");
            plot(app.UIAxes, X + r, Y, "blue");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'The Chart of Train Data')
            xlabel(app.UIAxes, 'Temperature')
            ylabel(app.UIAxes, 'Ratio')
            zlabel(app.UIAxes, 'Z')
            app.ChartButton = uibutton(app.GridLayout, 'push');
            app.ChartButton.ButtonPushedFcn = createCallbackFcn(app, @ChartButtonPushed, true);
            app.ChartButton.Text = 'Chart';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_07_chart_controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end