classdef app_08_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        AboutButton     matlab.ui.control.Button
        QuitButton      matlab.ui.control.Button
        SelectButton    matlab.ui.control.Button
        UIAxesGray      matlab.ui.control.UIAxes
        UIAxesOrigianl  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            choice = questdlg("Do you want to Quit?", "Quit", "Yes", "No", "No");
            if strcmp(choice, "Yes")
                delete(app)
            else
                return;
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox("This is a demo for image display!", "About", "help");
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg'; '*.bmp'; '*.png'; '*.*'}, "Open the image file");
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("NO image is seleted!", "ERROR");
            else
                filepath = strcat(pathname, filename);
                imgOriginal = imread(filepath);
                imshow(imgOriginal, "Parent", app.UIAxesOrigianl);

                imgGray = rgb2gray(imgOriginal);
                imshow(imgGray, "Parent", app.UIAxesGray);

                msgbox("Image loaded successfully!", "Information", "help");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesOrigianl = uiaxes(app.GridLayout);
            title(app.UIAxesOrigianl, 'Original')
            app.UIAxesOrigianl.XTick = [];
            app.UIAxesOrigianl.YTick = [];
            app.UIAxesGray = uiaxes(app.GridLayout);
            title(app.UIAxesGray, 'Gray')
            app.UIAxesGray.XTick = [];
            app.UIAxesGray.YTick = [];
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.AboutButton = uibutton(app.GridLayout, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_08_image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end