classdef app_09_meter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        QuitButton             matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        TestButton             matlab.ui.control.Button
        TemperatureGauge       matlab.ui.control.LinearGauge
        TemperatureGaugeLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            choice = questdlg("Do you want to QUIT?", "Warning", "Yes", "No", "No");
            if strcmp(choice, "Yes")
                delete(app)
            else
                return
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TemperatureGauge.Value = 0;
            app.TemperatureGaugeLabel.Text = "Temperature";
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            r = randi(100);
            app.TemperatureGauge.Value = r;
            app.TemperatureGaugeLabel.Text = sprintf("The current temperature is %d ℃", r);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TemperatureGaugeLabel = uilabel(app.GridLayout);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.GridLayout, 'linear');
            app.TestButton = uibutton(app.GridLayout, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_09_meter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end