classdef app_10_rotary_knob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TemperatureLabel  matlab.ui.control.Label
        QuitButton        matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        Knob              matlab.ui.control.Knob
        KnobLabel         matlab.ui.control.Label
        Gauge             matlab.ui.control.Gauge
        GaugeLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            choice = questdlg("Do you want to QUIT?", "WARNING", "Yes", "No", "No");
            if strcmp(choice, "Yes")
                delete(app)
            else
                return
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Knob.Value = 0;
            app.Gauge.Value = 0;
            app.TemperatureLabel = "Temperature";
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge.Value = changingValue;
            app.TemperatureLabel.Text = string(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GaugeLabel = uilabel(app.GridLayout);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.GridLayout, 'circular');
            app.KnobLabel = uilabel(app.GridLayout);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.GridLayout, 'continuous');
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.TemperatureLabel = uilabel(app.GridLayout);
            app.TemperatureLabel.Text = 'Temperature';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_10_rotary_knob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end