classdef app_11_slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        QuitButton            matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        Slider                matlab.ui.control.Slider
        SliderLabel           matlab.ui.control.Label
        KelvinGauge           matlab.ui.control.Gauge
        KelvinGaugeLabel      matlab.ui.control.Label
        FahrenheitGauge       matlab.ui.control.Gauge
        FahrenheitGaugeLabel  matlab.ui.control.Label
        CelsiusGauge          matlab.ui.control.Gauge
        CelsiusGaugeLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            choice = questdlg("Do you want to QUIT?", "WARNING", "Yes", "No", "No");
            if strcmp(choice, "Yes")
                delete(app)
            else
                return
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CelsiusGauge.Value = 0;
            app.CelsiusGaugeLabel.Text = "Celsius";
            app.FahrenheitGauge.Value = 32;
            app.FahrenheitGaugeLabel.Text = "Fahrenheit";
            app.KelvinGauge.Value = 273;
            app.KelvinGaugeLabel.Text = "Kelvin";
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.CelsiusGauge.Value = changingValue;
            app.CelsiusGaugeLabel.Text = string(app.CelsiusGauge.Value);
            app.FahrenheitGauge.Value = changingValue * 9/5 + 32;
            app.FahrenheitGaugeLabel.Text = string(app.FahrenheitGauge.Value);
            app.KelvinGauge.Value = changingValue + 273.15;
            app.KelvinGaugeLabel.Text = string(app.KelvinGauge.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CelsiusGaugeLabel = uilabel(app.GridLayout);
            app.CelsiusGaugeLabel.Text = 'Celsius';
            app.CelsiusGauge = uigauge(app.GridLayout, 'circular');
            app.FahrenheitGaugeLabel = uilabel(app.GridLayout);
            app.FahrenheitGaugeLabel.Text = 'Fahrenheit';
            app.FahrenheitGauge = uigauge(app.GridLayout, 'circular');
            app.FahrenheitGauge.Limits = [32 212];
            app.KelvinGaugeLabel = uilabel(app.GridLayout);
            app.KelvinGaugeLabel.Text = 'Kelvin';
            app.KelvinGauge = uigauge(app.GridLayout, 'circular');
            app.KelvinGauge.Limits = [273 375];
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_11_slider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end