classdef app_12_stepping_switch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Lamp         matlab.ui.control.Lamp
        QuitButton   matlab.ui.control.Button
        ResetButton  matlab.ui.control.Button
        Knob         matlab.ui.control.DiscreteKnob
        KnobLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        off = string(helper.OffOn.Off);

        white = string(helper.Lights.White);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            yes = string(helper.YesNo.Yes);
            no = string(helper.YesNo.No);
            choice = questdlg("Do you want to QUIT?", "WARNING", yes, no, no);
            if strcmp(choice, yes)
                delete(app)
            else
                return
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Knob.Value = app.off;
            app.KnobLabel.Text = "Knob";
            app.Lamp.Color = app.white;
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            red = string(helper.Lights.Red);
            yellow = string(helper.Lights.Yellow);
            green = string(helper.Lights.Green);

            low = string(helper.Level.Low);
            medium = string(helper.Level.Medium);

            value = app.Knob.Value;
            if strcmp(value, app.off)
                app.Lamp.Color = app.white;
            elseif strcmp(value, low)
                app.Lamp.Color = green;
            elseif strcmp(value, medium)
                app.Lamp.Color = yellow;
            else
                app.Lamp.Color = red;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.KnobLabel = uilabel(app.GridLayout);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.GridLayout, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Lamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_12_stepping_switch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end