classdef app_13_drop_down_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        QuitButton   matlab.ui.control.Button
        ResetButton  matlab.ui.control.Button
        DropDown     matlab.ui.control.DropDown
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            yes = string(helper.YesNo.Yes);
            no = string(helper.YesNo.No);
            choice = questdlg("Do you want to QUIT?", "WARNING", yes, no, no);
            switch choice
                case yes
                    delete(app)
                case no
                    return
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.DropDown.Value = "Select";
            cla(app.UIAxes);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            train = readmatrix("data/train.csv");
            xTrain = train(:, 1);
            yTrain = train(:, 2);

            test = readmatrix("data/test.csv");
            xTest = test(:, 1);
            yTest = test(:, 2);

            switch value
                case "Train Data"
                    plot(app.UIAxes, xTrain, yTrain, "or");
                    app.UIAxes.Title.String = "Train Data";
                case "Test Data"
                    plot(app.UIAxes, xTest, yTest, "*b");
                    app.UIAxes.Title.String = "Test Data";
                otherwise
                    cla(app.UIAxes)
                    app.UIAxes.Title.String = "Please select a data to display";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Select', 'Train Data', 'Test Data'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Select';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.GridLayout, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_13_drop_down_box

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end